classdef AlexNetTrainingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptimizationMethodDropDown      matlab.ui.control.DropDown
        OptimizationMethodDropDownLabel  matlab.ui.control.Label
        TRAININGPROGRESSButton          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        STARTTRAININGButton             matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        WeightsCheckBox                 matlab.ui.control.CheckBox
        ValidationImagesCheckBox        matlab.ui.control.CheckBox
        TrainingImagesCheckBox          matlab.ui.control.CheckBox
        NetworkAnalyzerCheckBox         matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        ADDITIONALWINDOWSLabel          matlab.ui.control.Label
        NumValidationImagesEditField    matlab.ui.control.NumericEditField
        NumValidationImagesEditFieldLabel  matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        NumTrainImagesEditField         matlab.ui.control.NumericEditField
        NumTrainImagesEditFieldLabel    matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CONFIGURATIONLabel              matlab.ui.control.Label
        ALEXNETTRAININGLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        miniBatchSize = 10;
        maxEpochs = 8;
        initialLearnRate = 1e-4;
        validationPatience = Inf;
        validationFrequency = 3;
        numTrainImages = 49;
        numValidationImages = 8;
        optimizationMethod = 'sgdm';
        windowNetworkAnalyzer = true;
        windowTrainingImage = true;
        windowValidationImage = true;
        windowWeights = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ChooseWindow;
            pause(3);
            app.delete;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete("netTransferAlimentosAlexNet.mat");
        end

        % Button pushed function: STARTTRAININGButton
        function STARTTRAININGButtonPushed(app, event)
            % Change button name to "Processing"
            app.TRAININGPROGRESSButton.Text = 'STARTING TRAINING PROCESS...';
            % Put text on top of icon
            app.TRAININGPROGRESSButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.TRAININGPROGRESSButton.BackgroundColor,15,1,300),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.TRAININGPROGRESSButton.Icon = wbar;
            pause(.5);
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(1/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            imds = imageDatastore('DATASETAlexNet\',...
                'IncludeSubfolders',true,...
                'LabelSource','foldernames');
            
            [imdsTrain,imdsValidation] = splitEachLabel(imds,0.7,'randomized');
            
            app.TRAININGPROGRESSButton.Text = 'RESIZING IMAGES...';
            currentProg = min(round((size(wbar,2)-2)*(2/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            idx = size(imds.Files,1);
            for i=1:1:idx
              D = cell2mat(imds.Files(i));
              Img = imread(D);
              
              I = imresize(Img, [227 227]);
              
              [a,b] = find(D =='\');
            
              S1 = D(1:b(6));
              S2 = 'DATASETAlexNet';
              S3 = D(b(7):size(D,2));
              fichero = [S1,S2,S3];
              imwrite(I,fichero);
            end

            pause(1);
            app.TRAININGPROGRESSButton.Text = 'CHOOSING TRAINING IMAGES...';
            currentProg = min(round((size(wbar,2)-2)*(3/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            numTrainImage = numel(imdsTrain.Labels);
            idx = randperm(numTrainImage,app.numTrainImages);
            if(app.windowTrainingImage)
                figure
                for i = 1:app.numTrainImages
                    subplot(7,7,i)
                    I = readimage(imdsTrain,idx(i));
                    imshow(I)
                end
            end

            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'LOADING ALEXNET...';
            currentProg = min(round((size(wbar,2)-2)*(4/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;
            % load netlayer
            net = alexnet;
            
            if(app.windowNetworkAnalyzer)
                analyzeNetwork(net)
            end

            inputSize = net.Layers(1).InputSize;
            
            % Replace Final Layers
            % The last three layers of the pretrained network net are configured for 1000 classes. These three layers must be fine-tuned for the new classification problem. Extract all layers, except the last three, from the pretrained network.
            
            layersTransfer = net.Layers(1:end-3);
            
            %Transfer the layers to the new classification task by replacing the last three layers with a fully connected layer, a softmax layer, and a classification output layer. Specify the options of the new fully connected layer according to the new data. Set the fully connected layer to have the same size as the number of classes in the new data. To learn faster in the new layers than in the transferred layers, increase the WeightLearnRateFactor and BiasLearnRateFactor values of the fully connected layer.
            numClasses = numel(categories(imdsTrain.Labels));
            
            layers = [
                layersTransfer
                fullyConnectedLayer(numClasses,'WeightLearnRateFactor',20,'BiasLearnRateFactor',20)
                softmaxLayer
                classificationLayer];
            
            %% Train Network
            
            pixelRange = [-30 30];
            imageAugmenter = imageDataAugmenter( ...
                'RandXReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange);
            
            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'CONFIGURING SETTINGS...';
            currentProg = min(round((size(wbar,2)-2)*(5/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            options = trainingOptions(app.optimizationMethod, ...
                'MiniBatchSize',app.miniBatchSize, ...
                'MaxEpochs',app.maxEpochs, ...
                'InitialLearnRate',app.initialLearnRate, ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',app.validationFrequency, ...
                'ValidationPatience',app.validationPatience, ...
                'Verbose',false, ...
                'Plots','training-progress');
            
            %Train the network that consists of the transferred and new layers. By default, trainNetwork uses a GPU if one is available 
            %(requires Parallel Computing Toolbox™ and a CUDA® enabled GPU with compute capability 3.0 or higher). Otherwise, it uses a CPU.
            % You can also specify the execution environment by using the 'ExecutionEnvironment' name-value pair argument of trainingOptions.
            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'TRAINING NETWORK...';
            currentProg = min(round((size(wbar,2)-2)*(6/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;
            
            netTransfer = trainNetwork(imdsTrain,layers,options);
            
            %% Guardar el modelo de la red en la variable netTransfer en el fichero  netTransferAlimentos.mat
            app.TRAININGPROGRESSButton.Text = 'SAVING NETWORK...';
            currentProg = min(round((size(wbar,2)-2)*(9/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;
            save netTransferAlimentosAlexNet netTransfer
            
            %% Classify Validation Images            
            [YPred,scores] = classify(netTransfer,imdsValidation);
            %Display four sample validation images with their predicted labels.
            if(app.windowValidationImage)
                idx = randperm(numel(imdsValidation.Files),6);
                figure
                for i = 1:6
                    subplot(3,2,i)
                    I = readimage(imdsValidation,idx(i));
                    imshow(I)
                    label = YPred(idx(i));
                    title(string(label));
                end
            end
            
            %% Visualización de los pesos
            if(app.windowWeights)
                % Get the network weights for the second convolutional layer
                w1 = netTransfer.Layers(2).Weights;
                
                % Scale and resize the weights for visualization
                w1 = mat2gray(w1);
                w1 = imresize(w1,5);
                
                % Display a montage of network weights. There are 96 individual sets of
                % weights in the first layer.
                figure
                montage(w1)
                title('First convolutional layer weights')
            end
            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'FINISHED!';
            currentProg = min(round((size(wbar,2)-2)*(10/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            pause(.5);
            % remove waitbar
            app.TRAININGPROGRESSButton.Icon = '';
            % Change button name
            app.TRAININGPROGRESSButton.Text = 'TRAINING PROGRESS (%)';
        end

        % Value changed function: NumValidationImagesEditField
        function NumValidationImagesEditFieldValueChanged(app, event)
            app.numValidationImages = app.NumValidationImagesEditField.Value;
        end

        % Value changed function: NumTrainImagesEditField
        function NumTrainImagesEditFieldValueChanged(app, event)
            app.numTrainImages = app.NumTrainImagesEditField.Value;
        end

        % Value changed function: ValidationFrequencyEditField
        function ValidationFrequencyEditFieldValueChanged(app, event)
            app.validationFrequency = app.ValidationFrequencyEditField.Value;
        end

        % Value changed function: InitialLearnRateEditField
        function InitialLearnRateEditFieldValueChanged(app, event)
            app.initialLearnRate = app.InitialLearnRateEditField.Value;
        end

        % Value changed function: MaxEpochsEditField
        function MaxEpochsEditFieldValueChanged(app, event)
            app.maxEpochs = app.MaxEpochsEditField.Value;
        end

        % Value changed function: MiniBatchSizeEditField
        function MiniBatchSizeEditFieldValueChanged(app, event)
            app.miniBatchSize = app.MiniBatchSizeEditField.Value;
        end

        % Value changed function: NetworkAnalyzerCheckBox
        function NetworkAnalyzerCheckBoxValueChanged(app, event)
            app.windowNetworkAnalyzer = app.NetworkAnalyzerCheckBox.Value;
        end

        % Value changed function: WeightsCheckBox
        function WeightsCheckBoxValueChanged(app, event)
            app.windowWeights = app.WeightsCheckBox.Value;
        end

        % Value changed function: TrainingImagesCheckBox
        function TrainingImagesCheckBoxValueChanged(app, event)
            app.windowTrainingImage = app.TrainingImagesCheckBox.Value;
        end

        % Value changed function: ValidationImagesCheckBox
        function ValidationImagesCheckBoxValueChanged(app, event)
            app.windowValidationImage = app.ValidationImagesCheckBox.Value;
        end

        % Value changed function: OptimizationMethodDropDown
        function OptimizationMethodDropDownValueChanged(app, event)
            app.optimizationMethod = app.OptimizationMethodDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ALEXNETTRAININGLabel = uilabel(app.UIFigure);
            app.ALEXNETTRAININGLabel.Text = 'ALEXNET TRAINING';
            app.CONFIGURATIONLabel = uilabel(app.UIFigure);
            app.CONFIGURATIONLabel.Text = 'CONFIGURATION';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '_______________________________________________';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MiniBatchSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MiniBatchSizeEditFieldValueChanged, true);
            app.MiniBatchSizeEditField.Value = 10;
            app.NumTrainImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumTrainImagesEditFieldLabel.Text = 'NumTrainImages';
            app.NumTrainImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumTrainImagesEditField.ValueChangedFcn = createCallbackFcn(app, @NumTrainImagesEditFieldValueChanged, true);
            app.NumTrainImagesEditField.Value = 49;
            app.MaxEpochsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEpochsEditFieldValueChanged, true);
            app.MaxEpochsEditField.Value = 8;
            app.InitialLearnRateEditFieldLabel = uilabel(app.UIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialLearnRateEditField.ValueChangedFcn = createCallbackFcn(app, @InitialLearnRateEditFieldValueChanged, true);
            app.InitialLearnRateEditField.Value = 0.0001;
            app.ValidationFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValidationFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @ValidationFrequencyEditFieldValueChanged, true);
            app.ValidationFrequencyEditField.Value = 3;
            app.NumValidationImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumValidationImagesEditFieldLabel.Text = 'NumValidationImages';
            app.NumValidationImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumValidationImagesEditField.ValueChangedFcn = createCallbackFcn(app, @NumValidationImagesEditFieldValueChanged, true);
            app.NumValidationImagesEditField.Value = 8;
            app.ADDITIONALWINDOWSLabel = uilabel(app.UIFigure);
            app.ADDITIONALWINDOWSLabel.Text = 'ADDITIONAL WINDOWS';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '__________________________________________________';
            app.NetworkAnalyzerCheckBox = uicheckbox(app.UIFigure);
            app.NetworkAnalyzerCheckBox.ValueChangedFcn = createCallbackFcn(app, @NetworkAnalyzerCheckBoxValueChanged, true);
            app.NetworkAnalyzerCheckBox.Text = ' Network Analyzer';
            app.NetworkAnalyzerCheckBox.Value = true;
            app.TrainingImagesCheckBox = uicheckbox(app.UIFigure);
            app.TrainingImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainingImagesCheckBoxValueChanged, true);
            app.TrainingImagesCheckBox.Text = ' Training Images';
            app.TrainingImagesCheckBox.Value = true;
            app.ValidationImagesCheckBox = uicheckbox(app.UIFigure);
            app.ValidationImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidationImagesCheckBoxValueChanged, true);
            app.ValidationImagesCheckBox.Text = ' Validation Images';
            app.ValidationImagesCheckBox.Value = true;
            app.WeightsCheckBox = uicheckbox(app.UIFigure);
            app.WeightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @WeightsCheckBoxValueChanged, true);
            app.WeightsCheckBox.Text = ' Weights';
            app.WeightsCheckBox.Value = true;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '__________________________________________________';
            app.STARTTRAININGButton = uibutton(app.UIFigure, 'push');
            app.STARTTRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTRAININGButtonPushed, true);
            app.STARTTRAININGButton.Icon = fullfile(pathToMLAPP, 'images', 'play-button.png');
            app.STARTTRAININGButton.Text = {' START '; ' TRAINING'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'return.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'images', 'reset.png');
            app.Button_2.Text = '';
            app.TRAININGPROGRESSButton = uibutton(app.UIFigure, 'push');
            app.TRAININGPROGRESSButton.Text = 'TRAINING PROGRESS (%)';
            app.OptimizationMethodDropDownLabel = uilabel(app.UIFigure);
            app.OptimizationMethodDropDownLabel.Text = 'OptimizationMethod';
            app.OptimizationMethodDropDown = uidropdown(app.UIFigure);
            app.OptimizationMethodDropDown.Items = {'sgdm', 'adam'};
            app.OptimizationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationMethodDropDownValueChanged, true);
            app.OptimizationMethodDropDown.Value = 'sgdm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AlexNetTrainingWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end