classdef ClassificationWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RESULTLabel                   matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        CLASSIFICATIONPROGRESSButton  matlab.ui.control.Button
        FILEEditField                 matlab.ui.control.EditField
        STARTCLASSIFICATIONButton     matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        CHOOSENETWORKLabel            matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        UPLOADIMAGEButton             matlab.ui.control.Button
        IMAGECLASSIFICATIONLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        NetChoosed = "AlexNet";
        J;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTCLASSIFICATIONButton
        function STARTCLASSIFICATIONButtonPushed(app, event)
            % Change button name to "Processing"
            app.CLASSIFICATIONPROGRESSButton.Text = 'LOADING TRAINED MODEL...';
            % Put text on top of icon
            app.CLASSIFICATIONPROGRESSButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CLASSIFICATIONPROGRESSButton.BackgroundColor,15,1,400),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CLASSIFICATIONPROGRESSButton.Icon = wbar;
            pause(.5);
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(1/10)),size(wbar,2)-2);
            RGB = app.CLASSIFICATIONPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.CLASSIFICATIONPROGRESSButton.Icon = RGB;

            %% Cargar el modelo entrenado según sea AlexNet o GoogleNet
            if (strcmp(app.NetChoosed,'AlexNet') == 1)
              load netTransferAlimentosAlexNet
            else
              load netTransferAlimentosGoogleNet
            end
            
            % Dimensión requerida por la red
            sz = netTransfer.Layers(1).InputSize;
            
            app.CLASSIFICATIONPROGRESSButton.Text = 'RESIZING IMAGE...';
            currentProg = min(round((size(wbar,2)-2)*(3/10)),size(wbar,2)-2);
            RGB = app.CLASSIFICATIONPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.CLASSIFICATIONPROGRESSButton.Icon = RGB;

            % Redimesión de la imagen
            if (strcmp(app.NetChoosed,'AlexNet') == 1)
               Ir = imresize(app.J, [227 227]);
            else
               Ir = imresize(app.J, [224 224]);
            end
            pause(.5);

            app.CLASSIFICATIONPROGRESSButton.Text = 'CLASSIFYING IMAGE...';
            currentProg = min(round((size(wbar,2)-2)*(6/10)),size(wbar,2)-2);
            RGB = app.CLASSIFICATIONPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.CLASSIFICATIONPROGRESSButton.Icon = RGB;
            [label, Error]  = classify(netTransfer,Ir);
            pause(.5);
            
            app.CLASSIFICATIONPROGRESSButton.Text = 'FINISHED!';
            currentProg = min(round((size(wbar,2)-2)*(10/10)),size(wbar,2)-2);
            RGB = app.CLASSIFICATIONPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.CLASSIFICATIONPROGRESSButton.Icon = RGB;

            app.Image.ImageSource = Ir;
            app.EditField.Value = string(label);
            drawnow;
            pause(.5);

            % remove waitbar
            app.CLASSIFICATIONPROGRESSButton.Icon = '';
            % Change button name
            app.CLASSIFICATIONPROGRESSButton.Text = 'CLASSIFICATION PROGRESS (%)';
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            if(app.DropDown.Value == 1) app.NetChoosed = "AlexNet";
            else app.NetChoosed = "GoogleNet";
            end
        end

        % Button pushed function: UPLOADIMAGEButton
        function UPLOADIMAGEButtonPushed(app, event)
            % seleccionar fichero de imagen
            [aa bb]=uigetfile({'*.*','All files'});
            app.J=imread([bb aa]);
            app.FILEEditField.Value = strcat('Image:', aa);
            app.FILEEditField.FontColor = "white";
            drawnow;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            MainMenuWindow;
            pause(3);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IMAGECLASSIFICATIONLabel = uilabel(app.UIFigure);
            app.IMAGECLASSIFICATIONLabel.Text = 'IMAGE CLASSIFICATION';
            app.UPLOADIMAGEButton = uibutton(app.UIFigure, 'push');
            app.UPLOADIMAGEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADIMAGEButtonPushed, true);
            app.UPLOADIMAGEButton.Icon = fullfile(pathToMLAPP, 'images', 'photo.png');
            app.UPLOADIMAGEButton.Text = 'UPLOAD IMAGE';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'ALEXNET', 'GOOGLENET'};
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'ALEXNET';
            app.CHOOSENETWORKLabel = uilabel(app.UIFigure);
            app.CHOOSENETWORKLabel.Text = 'CHOOSE NETWORK:';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'return.png');
            app.Button.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '________________________________';
            app.STARTCLASSIFICATIONButton = uibutton(app.UIFigure, 'push');
            app.STARTCLASSIFICATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTCLASSIFICATIONButtonPushed, true);
            app.STARTCLASSIFICATIONButton.Icon = fullfile(pathToMLAPP, 'images', 'play-button.png');
            app.STARTCLASSIFICATIONButton.Text = {' START '; ' CLASSIFICATION '};
            app.FILEEditField = uieditfield(app.UIFigure, 'text');
            app.FILEEditField.Editable = 'off';
            app.FILEEditField.Value = 'NO IMAGE UPLOADED!';
            app.CLASSIFICATIONPROGRESSButton = uibutton(app.UIFigure, 'push');
            app.CLASSIFICATIONPROGRESSButton.Text = 'CLASSIFICATION PROGRESS (%)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'question-sign.png');
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '___________________________________________';
            app.RESULTLabel = uilabel(app.UIFigure);
            app.RESULTLabel.Text = 'RESULT:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClassificationWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end