classdef GoogleNetTrainingWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptimizationMethodDropDown      matlab.ui.control.DropDown
        OptimizationMethodDropDownLabel  matlab.ui.control.Label
        NumValidationImagesEditField    matlab.ui.control.NumericEditField
        NumValidationImagesEditFieldLabel  matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        NumTrainImagesEditField         matlab.ui.control.NumericEditField
        NumTrainImagesEditFieldLabel    matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        TRAININGPROGRESSButton          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        STARTTRAININGButton             matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        WeightsCheckBox                 matlab.ui.control.CheckBox
        ValidationImagesCheckBox        matlab.ui.control.CheckBox
        TrainingImagesCheckBox          matlab.ui.control.CheckBox
        ConfusionMatrixCheckBox         matlab.ui.control.CheckBox
        NetworkAnalyzerCheckBox         matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        ADDITIONALWINDOWSLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CONFIGURATIONLabel              matlab.ui.control.Label
        GOOGLENETTRAININGLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        miniBatchSize = 10;
        maxEpochs = 6;
        initialLearnRate = 3e-4;
        validationPatience = Inf;
        numTrainImages = 16;
        numValidationImages = 8;
        optimizationMethod = 'sgdm';
        windowNetworkAnalyzer = true;
        windowTrainingImage = true;
        windowValidationImage = true;
        windowWeights = true;
        windowConfusionMatrix = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ChooseWindow;
            pause(3);
            app.delete;
        end

        % Button pushed function: STARTTRAININGButton
        function STARTTRAININGButtonPushed(app, event)
            % Change button name to "Processing"
            app.TRAININGPROGRESSButton.Text = 'STARTING TRAINING PROCESS...';
            % Put text on top of icon
            app.TRAININGPROGRESSButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.TRAININGPROGRESSButton.BackgroundColor,15,1,300),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.TRAININGPROGRESSButton.Icon = wbar;
            pause(.5);
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(1/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

             imds = imageDatastore('DATASETAlexNet\',...
            'IncludeSubfolders',true,...
            'LabelSource','foldernames');
        
            [imdsTrain,imdsValidation] = splitEachLabel(imds,0.7,'randomized');
            
            app.TRAININGPROGRESSButton.Text = 'RESIZING IMAGES...';
            currentProg = min(round((size(wbar,2)-2)*(2/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            idx = size(imds.Files,1);
            for i=1:1:idx
              D = cell2mat(imds.Files(i));
              Img = imread(D);
              
              I = imresize(Img, [224 224]);
              
              [a,b] = find(D =='\');
            
              S1 = D(1:b(6));
              S2 = 'DATASETGoogleNet';
              S3 = D(b(7):size(D,2));
              fichero = [S1,S2,S3];
              imwrite(I,fichero);
            end

            pause(1);
            app.TRAININGPROGRESSButton.Text = 'CHOOSING TRAINING IMAGES...';
            currentProg = min(round((size(wbar,2)-2)*(3/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            if(app.windowTrainingImage)
                numTrainImage = numel(imdsTrain.Labels);
                idx = randperm(numTrainImage,app.numTrainImages);
                figure
                for i = 1:app.numTrainImages
                    subplot(4,4,i)
                    I = readimage(imdsTrain,idx(i));
                    imshow(I)
                end
            end

            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'LOADING GOOGLENET...';
            currentProg = min(round((size(wbar,2)-2)*(4/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;
            % load netlayer
            net = googlenet;
            
            net.Layers(1)
            inputSize = net.Layers(1).InputSize;
            
            if(app.windowNetworkAnalyzer)
                analyzeNetwork(net)
            end

            if isa(net,'SeriesNetwork') 
              lgraph = layerGraph(net.Layers); 
            else
              lgraph = layerGraph(net);
            end 
            
            [learnableLayer,classLayer] = findLayersToReplace(lgraph);
            
            numClasses = numel(categories(imdsTrain.Labels));
            
            if isa(learnableLayer,'nnet.cnn.layer.FullyConnectedLayer')
                newLearnableLayer = fullyConnectedLayer(numClasses, ...
                    'Name','new_fc', ...
                    'WeightLearnRateFactor',10, ...
                    'BiasLearnRateFactor',10);
                
            elseif isa(learnableLayer,'nnet.cnn.layer.Convolution2DLayer')
                newLearnableLayer = convolution2dLayer(1,numClasses, ...
                    'Name','new_conv', ...
                    'WeightLearnRateFactor',10, ...
                    'BiasLearnRateFactor',10);
            end
            
            lgraph = replaceLayer(lgraph,learnableLayer.Name,newLearnableLayer);
            
            newClassLayer = classificationLayer('Name','new_classoutput');
            lgraph = replaceLayer(lgraph,classLayer.Name,newClassLayer);
            
            if(app.windowNetworkAnalyzer)
                figure('Units','normalized','Position',[0.3 0.3 0.4 0.4]);
                plot(lgraph)
                ylim([0,10])
            end

            layers = lgraph.Layers;
            connections = lgraph.Connections;
            
            layers(1:10) = freezeWeights(layers(1:10));
            lgraph = createLgraphUsingConnections(layers,connections);
            
            if(app.windowNetworkAnalyzer)
                analyzeNetwork(lgraph)
            end

            pixelRange = [-30 30];
            scaleRange = [0.9 1.1];
            imageAugmenter = imageDataAugmenter( ...
                'RandXReflection',true, ...
                'RandXTranslation',pixelRange, ...
                'RandYTranslation',pixelRange, ...
                'RandXScale',scaleRange, ...
                'RandYScale',scaleRange);
            
            augimdsTrain = augmentedImageDatastore(inputSize(1:2),imdsTrain, ...
                'DataAugmentation',imageAugmenter);
            
            augimdsValidation = augmentedImageDatastore(inputSize(1:2),imdsValidation);
            
            numaugsValidationImages = augimdsValidation.NumObservations;

            if(app.windowTrainingImage)
                idx = randperm(numaugsValidationImages,16);
                
                figure
                for i = 1:app.numTrainImages
                    subplot(4,4,i)
                    I = imread(augimdsValidation.Files{idx(i),1});
                    imshow(I)
                end
            end

            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'CONFIGURING SETTINGS...';
            currentProg = min(round((size(wbar,2)-2)*(5/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            valFrequency = floor(numel(augimdsTrain.Files)/app.miniBatchSize);
            options = trainingOptions(app.optimizationMethod, ...
                'MiniBatchSize',app.miniBatchSize, ...
                'MaxEpochs',app.maxEpochs, ...
                'InitialLearnRate',app.initialLearnRate, ...
                'Shuffle','every-epoch', ...
                'ValidationData',augimdsValidation, ...
                'ValidationFrequency',valFrequency, ...
                'ValidationPatience',app.validationPatience, ...
                'Verbose',false, ...
                'Plots','training-progress');

            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'TRAINING NETWORK...';
            currentProg = min(round((size(wbar,2)-2)*(6/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            netTransfer = trainNetwork(augimdsTrain,lgraph,options);
            
            app.TRAININGPROGRESSButton.Text = 'SAVING NETWORK...';
            currentProg = min(round((size(wbar,2)-2)*(9/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            save netTransferAlimentosGoogleNet netTransfer
            
            %% Validación del proceso de entrenamiento
            
            [YValidationPred,probs] = classify(netTransfer,augimdsValidation);
            validationAccuracy = mean(YValidationPred == imdsValidation.Labels)
            validationError = mean(YValidationPred ~= imdsValidation.Labels)
            
            YTrainPred = classify(netTransfer,augimdsTrain);
            trainError = mean(YTrainPred ~= imdsTrain.Labels);
            disp("Error Entrenamiento: " + trainError*100 + "%")
            disp("Error Validacion: " + validationError*100 + "%")
            
            %% Plot the confusion matrix. Display the precision and recall for each class by using column and row summaries. Sort the classes of the confusion matrix. The largest confusion is between unknown words and commands, up and off, down and no, and go and no.
            if(app.windowConfusionMatrix)
                figure('Units','normalized','Position',[0.2 0.2 0.5 0.5]);
                cm = confusionchart(YValidationPred,imdsValidation.Labels, ...
                    'ColumnSummary','column-normalized', ...
                    'RowSummary','row-normalized');
                cm.Title = 'Matriz Confusion Validacion GoogleNet';
            end

            if(app.windowValidationImage)
                idx = randperm(numel(imdsValidation.Files),app.numValidationImages);
                figure
                for i = 1:app.numValidationImages
                    subplot(4,2,i)
                    I = readimage(imdsValidation,idx(i));
                    imshow(I)
                    label = YValidationPred(idx(i));
                    title(string(label) + ", " + num2str(100*max(probs(idx(i),:)),3) + "%");
                end
            end
            
            %% Visualización de los pesos
            if(app.windowWeights)
                % Get the network weights for the second convolutional layer
                w1 = net.Layers(2).Weights;
                
                % Scale and resize the weights for visualization
                w1 = mat2gray(w1);
                w1 = imresize(w1,5);
                
                % Display a montage of network weights. There are 96 individual sets of
                % weights in the first layer.
                figure;
                montage(w1);
                title('First convolutional layer weights');
            end

            pause(0.5);
            app.TRAININGPROGRESSButton.Text = 'FINISHED!';
            currentProg = min(round((size(wbar,2)-2)*(10/10)),size(wbar,2)-2);
            RGB = app.TRAININGPROGRESSButton.Icon;
            RGB(2:end-1, 2:currentProg+1, 1) = 0.25391; % (royalblue)
            RGB(2:end-1, 2:currentProg+1, 2) = 0.41016;
            RGB(2:end-1, 2:currentProg+1, 3) = 0.87891;
            app.TRAININGPROGRESSButton.Icon = RGB;

            pause(.5);
            % remove waitbar
            app.TRAININGPROGRESSButton.Icon = '';
            % Change button name
            app.TRAININGPROGRESSButton.Text = 'TRAINING PROGRESS (%)';
        end

        % Value changed function: MiniBatchSizeEditField
        function MiniBatchSizeEditFieldValueChanged(app, event)
            app.miniBatchSize = app.MiniBatchSizeEditField.Value;
        end

        % Value changed function: MaxEpochsEditField
        function MaxEpochsEditFieldValueChanged(app, event)
            app.maxEpochs = app.MaxEpochsEditField.Value;
        end

        % Value changed function: InitialLearnRateEditField
        function InitialLearnRateEditFieldValueChanged(app, event)
            app.initialLearnRate = app.InitialLearnRateEditField.Value;
        end

        % Value changed function: NumValidationImagesEditField
        function NumValidationImagesEditFieldValueChanged(app, event)
            app.numValidationImages = app.NumValidationImagesEditField.Value;
        end

        % Value changed function: NumTrainImagesEditField
        function NumTrainImagesEditFieldValueChanged(app, event)
            app.numTrainImages = app.NumTrainImagesEditField.Value;
        end

        % Value changed function: NetworkAnalyzerCheckBox
        function NetworkAnalyzerCheckBoxValueChanged(app, event)
            app.windowNetworkAnalyzer = app.NetworkAnalyzerCheckBox.Value;
        end

        % Value changed function: ConfusionMatrixCheckBox
        function ConfusionMatrixCheckBoxValueChanged(app, event)
            app.windowConfusionMatrix = app.ConfusionMatrixCheckBox.Value;
        end

        % Value changed function: WeightsCheckBox
        function WeightsCheckBoxValueChanged(app, event)
            app.windowWeights = app.WeightsCheckBox.Value;
        end

        % Value changed function: TrainingImagesCheckBox
        function TrainingImagesCheckBoxValueChanged(app, event)
            app.windowTrainingImage = app.TrainingImagesCheckBox.Value;
        end

        % Value changed function: ValidationImagesCheckBox
        function ValidationImagesCheckBoxValueChanged(app, event)
            app.windowValidationImage = app.ValidationImagesCheckBox.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete("netTransferAlimentosGoogleNet.mat");
        end

        % Value changed function: OptimizationMethodDropDown
        function OptimizationMethodDropDownValueChanged(app, event)
            app.optimizationMethod = app.OptimizationMethodDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GOOGLENETTRAININGLabel = uilabel(app.UIFigure);
            app.GOOGLENETTRAININGLabel.Text = 'GOOGLENET TRAINING';
            app.CONFIGURATIONLabel = uilabel(app.UIFigure);
            app.CONFIGURATIONLabel.Text = 'CONFIGURATION';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '_______________________________________________';
            app.ADDITIONALWINDOWSLabel = uilabel(app.UIFigure);
            app.ADDITIONALWINDOWSLabel.Text = 'ADDITIONAL WINDOWS';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '__________________________________________________';
            app.NetworkAnalyzerCheckBox = uicheckbox(app.UIFigure);
            app.NetworkAnalyzerCheckBox.ValueChangedFcn = createCallbackFcn(app, @NetworkAnalyzerCheckBoxValueChanged, true);
            app.NetworkAnalyzerCheckBox.Text = ' Network Analyzer';
            app.NetworkAnalyzerCheckBox.Value = true;
            app.ConfusionMatrixCheckBox = uicheckbox(app.UIFigure);
            app.ConfusionMatrixCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConfusionMatrixCheckBoxValueChanged, true);
            app.ConfusionMatrixCheckBox.Text = ' Confusion Matrix';
            app.ConfusionMatrixCheckBox.Value = true;
            app.TrainingImagesCheckBox = uicheckbox(app.UIFigure);
            app.TrainingImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainingImagesCheckBoxValueChanged, true);
            app.TrainingImagesCheckBox.Text = ' Training Images';
            app.TrainingImagesCheckBox.Value = true;
            app.ValidationImagesCheckBox = uicheckbox(app.UIFigure);
            app.ValidationImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidationImagesCheckBoxValueChanged, true);
            app.ValidationImagesCheckBox.Text = ' Validation Images';
            app.ValidationImagesCheckBox.Value = true;
            app.WeightsCheckBox = uicheckbox(app.UIFigure);
            app.WeightsCheckBox.ValueChangedFcn = createCallbackFcn(app, @WeightsCheckBoxValueChanged, true);
            app.WeightsCheckBox.Text = ' Weights';
            app.WeightsCheckBox.Value = true;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '__________________________________________________';
            app.STARTTRAININGButton = uibutton(app.UIFigure, 'push');
            app.STARTTRAININGButton.ButtonPushedFcn = createCallbackFcn(app, @STARTTRAININGButtonPushed, true);
            app.STARTTRAININGButton.Icon = fullfile(pathToMLAPP, 'images', 'play-button.png');
            app.STARTTRAININGButton.Text = {' START '; ' TRAINING'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'images', 'return.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'images', 'reset.png');
            app.Button_2.Text = '';
            app.TRAININGPROGRESSButton = uibutton(app.UIFigure, 'push');
            app.TRAININGPROGRESSButton.Text = 'TRAINING PROGRESS (%)';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.UIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MiniBatchSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MiniBatchSizeEditFieldValueChanged, true);
            app.MiniBatchSizeEditField.Value = 10;
            app.NumTrainImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumTrainImagesEditFieldLabel.Text = 'NumTrainImages';
            app.NumTrainImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumTrainImagesEditField.ValueChangedFcn = createCallbackFcn(app, @NumTrainImagesEditFieldValueChanged, true);
            app.NumTrainImagesEditField.Value = 16;
            app.MaxEpochsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEpochsEditFieldValueChanged, true);
            app.MaxEpochsEditField.Value = 6;
            app.InitialLearnRateEditFieldLabel = uilabel(app.UIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialLearnRateEditField.ValueChangedFcn = createCallbackFcn(app, @InitialLearnRateEditFieldValueChanged, true);
            app.InitialLearnRateEditField.Value = 0.0003;
            app.NumValidationImagesEditFieldLabel = uilabel(app.UIFigure);
            app.NumValidationImagesEditFieldLabel.Text = 'NumValidationImages';
            app.NumValidationImagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumValidationImagesEditField.ValueChangedFcn = createCallbackFcn(app, @NumValidationImagesEditFieldValueChanged, true);
            app.NumValidationImagesEditField.Value = 8;
            app.OptimizationMethodDropDownLabel = uilabel(app.UIFigure);
            app.OptimizationMethodDropDownLabel.Text = 'OptimizationMethod';
            app.OptimizationMethodDropDown = uidropdown(app.UIFigure);
            app.OptimizationMethodDropDown.Items = {'sgdm', 'adam'};
            app.OptimizationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationMethodDropDownValueChanged, true);
            app.OptimizationMethodDropDown.Value = 'sgdm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GoogleNetTrainingWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end